classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Label          matlab.ui.control.Label
        Label_2        matlab.ui.control.Label
        EditField      matlab.ui.control.NumericEditField
        Label_4        matlab.ui.control.Label
        DatePicker     matlab.ui.control.DatePicker
        Label_7        matlab.ui.control.Label
        Spinner        matlab.ui.control.Spinner
        Label_8        matlab.ui.control.Label
        Spinner_2      matlab.ui.control.Spinner
        Label_10       matlab.ui.control.Label
        Spinner_3      matlab.ui.control.Spinner
        Label_9        matlab.ui.control.Label
        EditField_2    matlab.ui.control.EditField
        Button_7       matlab.ui.control.Button
        Label_15       matlab.ui.control.Label
        Slider         matlab.ui.control.Slider
        Slider_2Label  matlab.ui.control.Label
        Slider_2       matlab.ui.control.Slider
        Label_16       matlab.ui.control.Label
        Slider_3       matlab.ui.control.Slider
        Button_9       matlab.ui.control.Button
        Label_19       matlab.ui.control.Label
        EditField_5    matlab.ui.control.EditField
        Label_20       matlab.ui.control.Label
        EditField_6    matlab.ui.control.NumericEditField
        UITable2       matlab.ui.control.Table
        Button_10      matlab.ui.control.Button
        Button_11      matlab.ui.control.Button
        UITable        matlab.ui.control.Table
        Button_12      matlab.ui.control.Button
        Button_13      matlab.ui.control.Button
        Label_22       matlab.ui.control.Label
        Label_23       matlab.ui.control.Label
        Label_24       matlab.ui.control.Label
        Image          matlab.ui.control.Image
        TextArea       matlab.ui.control.TextArea
        Button_14      matlab.ui.control.Button
        Button_15      matlab.ui.control.Button
    end

    properties (Access = private)
        item = cell2table(cell(0, 7), "VariableNames", {'checked', 'name', 'price', ...
            'amount', 'emergency', 'preference', 'popularity'});
        rule = cell2table(cell(0, 4), "VariableNames", {'checked', 'item_label', ...
            'group_name', 'uplimitation'});
        ratio = zeros(1, 3);
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_12
        function Button_4Pushed(app, event)
            checked = cell2mat(app.item.checked);
            app.item(checked, :) = [];
            app.UITable.Data = app.item;
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            duration = days(app.DatePicker.Value - datetime('today'));
            new_item = {
                {false},               ... % 选中
                app.EditField_2.Value, ... % 商品名称
                app.EditField.Value,   ... % 单价
                app.Spinner_3.Value,   ... % 数量
                max(duration, 0),      ... % 紧急程度
                app.Spinner.Value,     ... % 喜爱程度
                app.Spinner_2.Value    ... % 必要程度
            };
            app.item = [app.item; new_item];
            app.UITable.Data = app.item;
            app.UITable.ColumnEditable = true;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            if event.Indices(2) == 1 || event.Indices(2) == 2
                app.item{event.Indices(1), event.Indices(2)} = {event.NewData};
            else
                app.item{event.Indices(1), event.Indices(2)} = event.NewData;
            end
        end

        % Button pushed function: Button_13
        function Button_6Pushed(app, event)
            app.item{:, 1} = {true};
            app.UITable.Data = app.item;
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            new_rule = {
                {false},                                          ... % 选中
                cell2mat(app.item.checked)' .* app.item.price',  ... % 商品是否选中
                app.EditField_5.Value,                            ... % 名称
                app.EditField_6.Value                             ... % 预算上限
            };
            try
                app.rule = [app.rule; new_rule];
            catch
                app.TextArea.Value = "要修改心愿并生成新预算方案，请先清除旧预算方案。";
            end
            app.UITable2.Data = app.rule;
            app.UITable2.ColumnEditable = true;
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            checked = cell2mat(app.rule.checked);
            app.rule(checked, :) = [];
            app.UITable2.Data = app.rule;
        end

        % Cell edit callback: UITable2
        function UITable2CellEdit(app, event)
            if event.Indices(2) == 1 || event.Indices(2) == 3
                app.rule{event.Indices(1), event.Indices(2)} = {event.NewData};
            else
                app.rule{event.Indices(1), event.Indices(2)} = event.NewData;
            end
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            app.rule{:, 1} = {true};
            app.UITable2.Data = app.rule;
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            app.ratio(2) = app.Slider_2.Value;
            n_goods = size(app.item, 1);
            new_result = strings(n_goods, 1);
            utility = [app.item.popularity, app.item.preference, app.item.emergency]';
            try
                rcmd = BranchBound(utility, app.ratio, app.rule.item_label, ...
                    app.rule.uplimitation, app.item.amount);
                for i=1:n_goods
                    new_result(i) = sprintf("心愿%s建议实现%d次", app.item.name{i}, rcmd(i));
                end
            catch
                new_result = "问题无解，请提高预算或修改错误的数据。";
            end
            app.TextArea.Value = new_result;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.ratio(1) = app.Slider.Value;
            n_goods = size(app.item, 1);
            new_result = strings(n_goods, 1);
            utility = [app.item.popularity, app.item.preference, app.item.emergency]';
            try
                rcmd = BranchBound(utility, app.ratio, app.rule.item_label, ...
                    app.rule.uplimitation, app.item.amount);
                for i=1:n_goods
                    new_result(i) = sprintf("心愿%s建议实现%d次", app.item.name{i}, rcmd(i));
                end
            catch
                new_result = "问题无解，请提高预算或修改错误的数据。";
            end
            app.TextArea.Value = new_result;
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            app.ratio(3) = app.Slider_3.Value;
            n_goods = size(app.item, 1);
            new_result = strings(n_goods, 1);
            utility = [app.item.popularity, app.item.preference, app.item.emergency]';
            try
                rcmd = BranchBound(utility, app.ratio, app.rule.item_label, ...
                    app.rule.uplimitation, app.item.amount);
                for i=1:n_goods
                    new_result(i) = sprintf("心愿%s建议实现%d次", app.item.name{i}, rcmd(i));
                end
            catch
                new_result = "问题无解，请提高预算或修改错误的数据。";
            end
            app.TextArea.Value = new_result;
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            app.item{:,1} = {false};
            app.UITable.Data = app.item;
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            app.rule{:,1} = {false};
            app.UITable2.Data = app.rule;
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '添加心愿';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '单价';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Limits = [0.01 Inf];
            app.EditField.ValueDisplayFormat = '%0.2f';
            app.EditField.Value = 1;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '紧急程度';
            app.DatePicker = uidatepicker(app.UIFigure);
            app.DatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '喜爱程度';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.Limits = [1 10];
            app.Spinner.Value = 1;
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '流行程度';
            app.Spinner_2 = uispinner(app.UIFigure);
            app.Spinner_2.Limits = [1 10];
            app.Spinner_2.Value = 1;
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '数量';
            app.Spinner_3 = uispinner(app.UIFigure);
            app.Spinner_3.Limits = [1 Inf];
            app.Spinner_3.Value = 1;
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '心愿名称';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '确认添加';
            app.Label_15 = uilabel(app.UIFigure);
            app.Label_15.Text = '流行程度';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_2Label = uilabel(app.UIFigure);
            app.Slider_2Label.Text = '喜爱程度';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [0 1];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Label_16 = uilabel(app.UIFigure);
            app.Label_16.Text = '紧急程度';
            app.Slider_3 = uislider(app.UIFigure);
            app.Slider_3.Limits = [0 1];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '设置';
            app.Label_19 = uilabel(app.UIFigure);
            app.Label_19.Text = '分组名称';
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.Label_20 = uilabel(app.UIFigure);
            app.Label_20.Text = '预算上限';
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6.Limits = [0.01 Inf];
            app.EditField_6.ValueDisplayFormat = '%.2f';
            app.EditField_6.Value = 1;
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {''; '选中心愿代码'; '名称'; '预算上限'};
            app.UITable2.RowName = {};
            app.UITable2.CellEditCallback = createCallbackFcn(app, @UITable2CellEdit, true);
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '删除选中项';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '全选';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {''; '心愿名称'; '单价'; '数量'; '剩余日'; '喜爱'; '流行'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_12.Text = '删除选中项';
            app.Button_13 = uibutton(app.UIFigure, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_13.Text = '全选';
            app.Label_22 = uilabel(app.UIFigure);
            app.Label_22.Text = '心愿单排序系统';
            app.Label_23 = uilabel(app.UIFigure);
            app.Label_23.Text = '设置预算';
            app.Label_24 = uilabel(app.UIFigure);
            app.Label_24.Text = '设置偏好';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'tile.png';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'你带上我，我带上软件。'; '你负责浪漫，我负责算。'};
            app.Button_14 = uibutton(app.UIFigure, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = '全不选';
            app.Button_15 = uibutton(app.UIFigure, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = '全不选';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end